classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AngleofAttackGauge              matlab.ui.control.SemicircularGauge
        SpeedReadoutLabel               matlab.ui.control.Label
        WindSpeedLabel                  matlab.ui.control.Label
        CloudBlowing                    matlab.ui.control.Image
        BlueCloud1                      matlab.ui.control.Image
        BlueCloud2                      matlab.ui.control.Image
        EditableLabel                   matlab.ui.control.EditField
        RefreshButton                   matlab.ui.control.Button
        SerialPortDropDown              matlab.ui.control.DropDown
        SerialPortLabel                 matlab.ui.control.Label
        ConnectionLamp                  matlab.ui.control.Lamp
        ConnectionLampLabel             matlab.ui.control.Label
        DisconnectButton                matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        DynamicPressureGauge            matlab.ui.control.LinearGauge
        AoAPortPressureDifferenceGauge  matlab.ui.control.LinearGauge
        SpeedSelectionKnob              matlab.ui.control.Slider
        MeasuredAirspeedGauge           matlab.ui.control.Gauge
        SETSPEEDButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PlotsTab                        matlab.ui.container.Tab
        PressureArrowLabel              matlab.ui.control.Label
        VelocityArrowLabel              matlab.ui.control.Label
        AOALabel                        matlab.ui.control.Label
        LiftLabel                       matlab.ui.control.Label
        AirfoilPressureLabel            matlab.ui.control.Label
        dPalphaLabel                    matlab.ui.control.Label
        AirfoilPressureAxes             matlab.ui.control.UIAxes
        LiftAxes                        matlab.ui.control.UIAxes
        AngleofAttackGaugeAxes          matlab.ui.control.UIAxes
        HelpTab                         matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        Features                        matlab.ui.container.Tab
        FeatureHTML                     matlab.ui.control.HTML
        WindSliderAxes                  matlab.ui.control.UIAxes
        JetSpeedAxes                    matlab.ui.control.UIAxes
        StopSignButton                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Defines set of global constants
        TIMER_OBJ;              % Timer that executes readData()
        TIME_INTERVAL = 0.05;   % Seconds. Interval for the TIMER_OBJ
        CONNECTED;              % Flag for whether or not the app is connected to a serial port
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        MAX_VECTOR_LENGTH = 1000;   % Defines the length of uutSpeeds and aoaPres used for plotting
        SERIAL_PORT;        % Serial port object

        % Raw Needle Image
        NeedleImageOriginal
        NeedleAlphaOriginal

        % Initialize Handles
        BalloonHandle
        BottomVelocityHandle
        CloudHandle
        FlameHandle
        JetHandle
        NeedleHandle
        PlaneHandle
        PressureUpHandle
        PressureDownHandle
        TopVelocityHandle
        WeightHandle

    end
    
    properties (Access = public)
        uutSpeeds = [];    % Vector of airspeed values measured by pitot probe with alpha ports
        uutPres = [];
        aoaPres = [];      % Vector of measured pressure differences between alpha ports
        aoaVec = [];

        uutSpeedsConverted = [];    % Vector of airspeed values measured by pitot probe with alpha ports
        uutPresConverted = [];
        aoaPresConverted = [];      % Vector of measured pressure differences between alpha ports
   
    end


    methods (Access = private)
        
        % AOA Gauge Functions
        function updateGauge(app, value)
            % Clamp the value between -40 and 40 AOA
            value = max(-40, min(40, value));

            % Map the value [-40 to 40] to an angle [270 to 90]
            angleDeg = -90 + ((value + 40) / 80) * 180;

            % Rotate the needle image
            rotatedNeedle = imrotate(app.NeedleImageOriginal, -angleDeg, 'crop');
            rotatedAlpha = imrotate(app.NeedleAlphaOriginal,-angleDeg,'crop');

            % Update the image on the axes
            set(app.NeedleHandle, 'CData', rotatedNeedle,'AlphaData',rotatedAlpha);
        end
        
        % Set Speed Functions
        function startCloudDrag(app)
            fig = ancestor(app.WindSliderAxes, 'figure');

            % Set up dragging callbacks
            fig.WindowButtonMotionFcn = @(~,~) app.dragCloud();
            fig.WindowButtonUpFcn = @(~,~) stopCloudDrag(app, fig);
        end

        function dragCloud(app)
            % Get mouse position in axes
            cp = app.WindSliderAxes.CurrentPoint;
            newX = cp(1,1);

            % Clamp to valid range [1, 40]
            newX = max(1, min(40, newX));

            % Move cloud image
            app.CloudHandle.XData = [newX - 2.5, newX + 2.5];

            % Update values
            app.SpeedSelectionKnob.Value = round(newX);
        end 

        function stopCloudDrag(app, fig)
            fig.WindowButtonMotionFcn = '';
            fig.WindowButtonUpFcn = '';
        end
        
        % Stop Sign Pressed Function
        function STOPSIGNButtonPushed(app)
            if app.CONNECTED
                writeline(app.SERIAL_PORT, "0");
                app.SpeedSelectionKnob.Value = 0;
                app.CloudHandle.XData = [-2.5, 2.5];
            end
        end

        % Lift function for balloon graphic
        function pressureLiftUpdate(app,dPalpha,speed,aoa)
            % Reset Initial Positions
            planeY = [-1 3];
            balloonX = [-1 1];
            balloonY = [-2 1];
            weightX = [-1 1];
            weightY = [1 4];
            planeCenter = 1; % center plane with edges of balloon 

            % Scale top and bottom balloons
            balloonScale = 1 - dPalpha * 0.001; % negative dPalpha makes top balloon INFLATE
            weightScale = 1 + dPalpha * 0.001; % reverse effect on weight
            
            % Scale balloons and adjust positions
            scaleBalloonX = balloonX * balloonScale;
            scaleBalloonY = balloonY * balloonScale;
            scaleWeightX = weightX * weightScale;
            scaleWeightY = weightY * weightScale;
            
            % Check speed range validity and assign scaling parameters
            if speed < 20 || speed > 35 % no AOA data
                lift = 0;
            else % AOA eligible readings
                % Calculate lift based on AOA
                maxAOA = 30; % [degrees]
                if abs(aoa) < maxAOA
                    lift = -0.025 * (abs(dPalpha) * sind(aoa)); % generic lift formula
                else % stall conditions
                    lift = 6;
                end
            end
            
            % Adjust positions to show lift for AOA and scale
            set(app.PlaneHandle,'YData',(planeY + lift))
            set(app.BalloonHandle, 'XData',scaleBalloonX,'YData',[(scaleBalloonY + lift) (planeCenter + lift)])
            set(app.WeightHandle,'XData',scaleWeightX,'YData',[(planeCenter + lift) (scaleWeightY + lift)]);          

        end
        
        % Pressure function for airfoil graphic
        function airfoilPressureUpdate(app,dPalpha)
            % Reset initial positions
            downPressX = [-0.25 0.25];
            downPressY = [-2.5 -1.5];
            upPressX = [-0.25 0.25];
            upPressY = [1.5 2.5];
            topVelX = [-0.5 0.5];
            topVelY = [-1 -1.25];
            bottomVelX = [-0.5 0.5];
            bottomVelY = [1 1.25];

            % Set scales
            scale1 = 1 + dPalpha * 0.001; % pressure down and bottom velocity
            scale2 = 1 - dPalpha * 0.001; % pressure up and top velocity

            % Scale arrows
            set(app.PressureDownHandle, 'XData', downPressX,'YData',[(downPressY(1)*scale1), downPressY(2)]);
            set(app.PressureUpHandle, 'XData', upPressX,'YData',[upPressY(1), (upPressY(2) * scale2)]);
            set(app.TopVelocityHandle, 'XData', [topVelX(1), (topVelX(2) * scale2 * 3)],'YData',topVelY);
            set(app.BottomVelocityHandle, 'XData', [bottomVelX(1), (bottomVelX(2) * scale1 * 3)],'YData',bottomVelY);

        end
       
        % Read Data Function
        function readData(app, ~, ~)
            numLinesToRead = 30;
            flush(app.SERIAL_PORT);      % Clear unread messages
            data = zeros(numLinesToRead, 3);
            while app.SERIAL_PORT.NumBytesAvailable < 4    % Wait until a full message is ready
            end
        
            for i = 1:numLinesToRead
                if i == 1
                    useless = readline(app.SERIAL_PORT);   % Dump the first line into an unused variable
                end
                string     = readline(app.SERIAL_PORT);
                data(i, :) = str2double(strsplit(string, "\t"));
                
            end
            % Parse data
            aoa_dat = mean(data(data(:, 1)==1, :), 1);
            ref_dat = mean(data(data(:, 1)==3, :), 1);
            uut_dat = mean(data(data(:, 1)==4, :), 1);

            % Update speed and aoa vectors
            app.uutSpeeds      = circshift(app.uutSpeeds, -1);
            app.uutSpeeds(end) = uut_dat(3);
            app.uutPres        = circshift(app.uutPres, -1);
            app.uutPres(end)   = uut_dat(2);
            app.aoaPres        = circshift(app.aoaPres, -1);
            app.aoaPres(end)   = aoa_dat(2);
            app.aoaVec         = circshift(app.aoaVec, -1);

            app.uutSpeedsConverted      = circshift(app.uutSpeeds, -1);
            app.uutSpeedsConverted(end) = uut_dat(3);
            app.uutPresConverted        = circshift(app.uutPres, -1);
            app.uutPresConverted(end)   = uut_dat(2);
            app.aoaPresConverted        = circshift(app.aoaPres, -1);
            app.aoaPresConverted(end)   = aoa_dat(2);
            
            % Update Gauges
            app.DynamicPressureGauge.Value  = ref_dat(2);
            app.MeasuredAirspeedGauge.Value = ref_dat(3);
            app.AoAPortPressureDifferenceGauge.Value = aoa_dat(2);
            app.aoaVec(end) = interpAoa(aoa_dat(2), ref_dat(2));
            app.AngleofAttackGauge.Value = app.aoaVec(end);

            % ================AOA Gauge==============================
            aoa = app.AngleofAttackGauge.Value;
            speed = app.MeasuredAirspeedGauge.Value;

            % Keep image stationary until in valid AOA range
            if speed < 20 || speed > 35
                set(app.NeedleHandle, 'CData', app.NeedleImageOriginal,'AlphaData',app.NeedleAlphaOriginal);
            else
                updateGauge(app, aoa)
            end

            %==============Jet Speed Graphic===========================
            % Update Jet Speed
            speed = app.MeasuredAirspeedGauge.Value;

            % Scale flame based on speed
            flameLength = -(1.8 + (speed/40) * 3.5);
            flameHeight = 0.6 + (speed/40) * 1.5;
            set(app.FlameHandle,'XData', [flameLength 0],'YData',[-flameHeight flameHeight]);

            % Update Label
            app.SpeedReadoutLabel.Text = sprintf('MEASURED AIRSPEED = %.3g m/s',speed);

            %==============Lift Balloon Graphic=========================
            aoa = app.AngleofAttackGauge.Value;
            speed = app.MeasuredAirspeedGauge.Value;

            aoaPortPress = app.AoAPortPressureDifferenceGauge.Value;
            pressureLiftUpdate(app,aoaPortPress,speed,aoa)
            app.dPalphaLabel.Text = sprintf('Pressure_{ Top} - Pressure_{ Bottom} = %g Pa',aoaPortPress);
            
            % ====================Airfoil Pressure Graphic=================
            aoaPortPress = app.AoAPortPressureDifferenceGauge.Value;
            airfoilPressureUpdate(app,aoaPortPress)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('AoaData')
            addpath('Images')
            % Initialize vectors
            app.uutSpeeds = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoaPres = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoaVec = zeros(1, app.MAX_VECTOR_LENGTH);
            app.uutPres = zeros(1, app.MAX_VECTOR_LENGTH);

            app.uutSpeedsConverted = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoaPresConverted = zeros(1, app.MAX_VECTOR_LENGTH);
            app.uutPresConverted = zeros(1, app.MAX_VECTOR_LENGTH);
            
            % Create serial port list
            portsAvail = serialportlist;
            app.SerialPortDropDown.Items = portsAvail;
            app.CONNECTED = 0;
            app.ConnectionLamp.Color = app.RED_COLOR;

            % Initialize, but don't start, the timer
            app.TIMER_OBJ = timer;
            app.TIMER_OBJ.TimerFcn = @app.readData;
            app.TIMER_OBJ.ExecutionMode = 'fixedRate';
            app.TIMER_OBJ.Period = app.TIME_INTERVAL;
            app.TIMER_OBJ.BusyMode = 'drop';

            % ====================AOA Gauge==============================
            % Load background gauge image
            [bgImg, ~, bgAlpha] = imread('AOAGauge.png');
            AOAgauge = imshow(bgImg,'Parent', app.AngleofAttackGaugeAxes,'XData', [-1, 1], 'YData', [-2, 2]); % load and display background image
            set(AOAgauge, 'AlphaData', bgAlpha)
            hold(app.AngleofAttackGaugeAxes,'on')
            
            % Load needle image
            [needleImg, ~, needleAlpha] = imread('CartoonAirplane.png');
            app.NeedleImageOriginal = needleImg;
            app.NeedleAlphaOriginal = needleAlpha;

            % Display needle image centered over the gauge
            app.NeedleHandle = image(app.AngleofAttackGaugeAxes, [-0.6 2.5],[-0.75 0.75], needleImg, 'AlphaData', needleAlpha);

            % Configure gauge axes
            app.AngleofAttackGaugeAxes.XLim = [-1.5, 2.5];
            app.AngleofAttackGaugeAxes.YLim = [-2.2, 2.5];
            app.AngleofAttackGaugeAxes.DataAspectRatio = [1 1 1];
            app.AngleofAttackGaugeAxes.Visible = 'off';

            % ===================Stop Button============================
            [stopImg, ~, stopAlpha] = imread('StopSign.png');
            stopSign = imshow(stopImg, 'Parent', app.StopSignButton);
            set(stopSign, 'AlphaData', stopAlpha)
            set(stopSign, 'ButtonDownFcn', @(src, event) STOPSIGNButtonPushed(app));

            % ===================Wind Speed Slider=========================
            % Set up axes
            axis(app.WindSliderAxes, [-2.5 40  0 5]);
            app.WindSliderAxes.XLim = [-2.5 42.5];
            disableDefaultInteractivity(app.WindSliderAxes);
            hold(app.WindSliderAxes, 'on');

            % Add speed zones
            rectangle(app.WindSliderAxes, 'Position', [0 0 20 5], 'FaceColor', [0.6 0.9 1], 'EdgeColor', 'none'); % Low = blue
            rectangle(app.WindSliderAxes, 'Position', [20 0 35 5], 'FaceColor', [0.5 1.0 0.5], 'EdgeColor', 'none'); % Med = green
            rectangle(app.WindSliderAxes, 'Position', [35 0 40 5], 'FaceColor', [1.0 0.6 0.6], 'EdgeColor', 'none'); % High = red

            % Draw tick marks and labels
            majorTicks = 0:5:40;
            minorTicks = setdiff(0:1:40, majorTicks);

            % Minor ticks
            for i = 1:length(minorTicks)
                x = minorTicks(i);
                line(app.WindSliderAxes, [x x], [0 0.5], 'Color', 'k', 'LineWidth', 0.75);
            end

            % Major ticks
            for i = 1:length(majorTicks)
                x = majorTicks(i);
                % Tick line
                line(app.WindSliderAxes, [x x], [0 1], 'Color', 'k', 'LineWidth', 2);

                % Tick label below
                text(app.WindSliderAxes, x, -0.1, sprintf('%d',x), 'HorizontalAlignment','center','VerticalAlignment','bottom','FontSize',15, 'Color','k')
            end

            % Cloud Image
            [cloudImg, ~, cloudAlpha] = imread('WhiteCloud.png');
            app.CloudHandle = imshow(cloudImg, 'Parent', app.WindSliderAxes,'XData',[-2.5 2.5],'YData',[0 5]);
            set(app.CloudHandle, 'AlphaData', cloudAlpha);

            % Enable dragging
            app.CloudHandle.ButtonDownFcn = @(src, event) startCloudDrag(app);

            % =====================Jet Measured Speed======================
            % Set axes
            app.JetSpeedAxes.XLim = [-6, 4];
            app.JetSpeedAxes.YLim = [-2, 2];
            axis(app.JetSpeedAxes, 'off');
            hold(app.JetSpeedAxes, 'on');
            
            % Load images
            [jetImg, ~, jetAlpha] = imread('FighterJet.png');
            [flameImg, ~, flameAlpha] = imread('Flame.png');

            % Display fixed jet and flame
            app.JetHandle = imshow(jetImg,'Parent',app.JetSpeedAxes, 'XData', [0, 4], 'YData', [-2, 2]);
            set(app.JetHandle,'AlphaData',jetAlpha);
            app.FlameHandle = imshow(flameImg,'Parent',app.JetSpeedAxes, 'XData', [-1.8, 0], 'YData', [-0.6, 0.6]);
            set(app.FlameHandle, 'AlphaData',flameAlpha);

            % Speed Label
            app.SpeedReadoutLabel.Text = 'MEASURED AIRSPEED = 0 m/s';

            % ===============Pressure Balloon Graphic====================
            % Set axes limits
            app.LiftAxes.XLim = [-5 5];
            app.LiftAxes.YLim = [-10 10];
            axis(app.LiftAxes, 'off');
            hold(app.LiftAxes, 'on');

            % Load images
            [balloonImg, ~, balloonAlpha] = imread('Balloon.png');
            [weightImg, ~, weightAlpha] = imread('Weight.png');
            [planeImg, ~, planeAlpha] = imread('CartoonAirplane.png');

            % Show balloon and plane
            app.BalloonHandle = imshow(balloonImg, 'Parent',app.LiftAxes,'XData', [-1 1], 'YData',[-2 1]);
            set(app.BalloonHandle, 'AlphaData',balloonAlpha);

            app.WeightHandle = imshow(weightImg, 'Parent',app.LiftAxes,'XData',[-1 1], 'YData', [1 4]);
            set(app.WeightHandle, 'AlphaData',weightAlpha);

            app.PlaneHandle = imshow(planeImg, 'Parent',app.LiftAxes,'XData', [-4.5 4.5],'YData', [-1 3]);
            set(app.PlaneHandle, 'AlphaData',planeAlpha);

            % ==================Airfoil Pressure Graphic ==================
            % Configure gauge axes
            app.AirfoilPressureAxes.XLim = [-3, 3];
            app.AirfoilPressureAxes.YLim = [-3, 3];
            app.AirfoilPressureAxes.Visible = 'off';
            
            % Add airfoil image
            [airfoilImg, ~, airfoilAlpha] = imread('Airfoil.png');
            airfoil = imshow(airfoilImg,'Parent',app.AirfoilPressureAxes,'XData', [-2 2],'YData',[-0.5 0.5]);
            set(airfoil, 'AlphaData',airfoilAlpha);
            hold(app.AirfoilPressureAxes, 'on');

            % Add balanced arrows
            [topVelImg, ~, topVelAlpha] = imread('VelocityArrow.png');
            [bottomVelImg, ~, bottomVelAlpha] = imread('VelocityArrow.png');
            [upPressImg, ~, upPressAlpha] = imread('UpPressureArrow.png');
            [downPressImg, ~, downPressAlpha] = imread('DownPressureArrow.png');

            app.TopVelocityHandle = imshow(topVelImg, 'Parent', app.AirfoilPressureAxes,'XData', [-0.5 0.5], 'YData',[-1 -1.25]);
            set(app.TopVelocityHandle, 'AlphaData', topVelAlpha);

            app.BottomVelocityHandle = imshow(bottomVelImg, 'Parent', app.AirfoilPressureAxes,'XData', [-0.5 0.5], 'YData', [1 1.25]);
            set(app.BottomVelocityHandle, 'AlphaData', bottomVelAlpha);

            app.PressureUpHandle = imshow(upPressImg, 'Parent', app.AirfoilPressureAxes,'XData', [-0.25 0.25], 'YData',[1.5 2.5]);
            set(app.PressureUpHandle, 'AlphaData', upPressAlpha);

            app.PressureDownHandle = imshow(downPressImg, 'Parent', app.AirfoilPressureAxes,'XData', [-0.25 0.25], 'YData', [-2.5 -1.5]);
            set(app.PressureDownHandle, 'AlphaData', downPressAlpha);           
            
            % Pressure Label
            app.dPalphaLabel.Text = sprintf('Pressure_{ Top} - Pressure_{ Bottom} = 0 Pa');

        end

        % Button pushed function: SETSPEEDButton
        function SETSPEEDButtonPushed(app, event)
            if app.CONNECTED
                pwrSetting = ((app.SpeedSelectionKnob.Value - 4.542)/0.3725);
                writeline(app.SERIAL_PORT, num2str(pwrSetting));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            portsAvail = serialportlist;
            app.SerialPortDropDown.Items = portsAvail;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.SERIAL_PORT)
                app.SERIAL_PORT = serialport(app.SerialPortDropDown.Value, 9600);
                app.CONNECTED = 1;
                app.ConnectionLamp.Color = app.GREEN_COLOR;
                start(app.TIMER_OBJ)
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.SERIAL_PORT = [];
            app.CONNECTED = 0;
            app.ConnectionLamp.Color = app.RED_COLOR;
            stop(app.TIMER_OBJ);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StopSignButton = uiaxes(app.UIFigure);
            app.JetSpeedAxes = uiaxes(app.UIFigure);
            app.WindSliderAxes = uiaxes(app.UIFigure);
            app.WindSliderAxes.XLim = [0 40];
            app.WindSliderAxes.YTickLabel = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.AngleofAttackGaugeAxes = uiaxes(app.PlotsTab);
            app.LiftAxes = uiaxes(app.PlotsTab);
            app.AirfoilPressureAxes = uiaxes(app.PlotsTab);
            app.dPalphaLabel = uilabel(app.PlotsTab);
            app.dPalphaLabel.Interpreter = 'tex';
            app.dPalphaLabel.Text = '';
            app.AirfoilPressureLabel = uilabel(app.PlotsTab);
            app.AirfoilPressureLabel.Text = 'SPEED AND PRESSURE RELATIONSHIP';
            app.LiftLabel = uilabel(app.PlotsTab);
            app.LiftLabel.Text = 'LIFT AND WEIGHT RELATIONSHIP';
            app.AOALabel = uilabel(app.PlotsTab);
            app.AOALabel.Text = 'MEASURED ANGLE OF ATTACK';
            app.VelocityArrowLabel = uilabel(app.PlotsTab);
            app.VelocityArrowLabel.Text = 'Velocity';
            app.PressureArrowLabel = uilabel(app.PlotsTab);
            app.PressureArrowLabel.Text = 'Pressure';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HTML = uihtml(app.HelpTab);
            app.HTML.HTMLSource = 'helpText.html';
            app.Features = uitab(app.TabGroup);
            app.Features.Title = 'Features';
            app.FeatureHTML = uihtml(app.Features);
            app.FeatureHTML.HTMLSource = 'featureFunctions.html';
            app.SETSPEEDButton = uibutton(app.UIFigure, 'push');
            app.SETSPEEDButton.ButtonPushedFcn = createCallbackFcn(app, @SETSPEEDButtonPushed, true);
            app.SETSPEEDButton.Text = 'SET SPEED';
            app.MeasuredAirspeedGauge = uigauge(app.UIFigure, 'circular');
            app.MeasuredAirspeedGauge.Limits = [0 40];
            app.MeasuredAirspeedGauge.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.MeasuredAirspeedGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.SpeedSelectionKnob = uislider(app.UIFigure);
            app.SpeedSelectionKnob.Limits = [0 40];
            app.SpeedSelectionKnob.MajorTicks = [0 5 10 15 20 25 30 35];
            app.AoAPortPressureDifferenceGauge = uigauge(app.UIFigure, 'linear');
            app.AoAPortPressureDifferenceGauge.Limits = [-500 500];
            app.DynamicPressureGauge = uigauge(app.UIFigure, 'linear');
            app.DynamicPressureGauge.Limits = [0 1500];
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.SerialPortLabel = uilabel(app.UIFigure);
            app.SerialPortLabel.Text = 'Serial Port:';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.EditableLabel = uieditfield(app.UIFigure, 'text');
            app.EditableLabel.Value = 'WIND TUNNEL ';
            app.BlueCloud2 = uiimage(app.UIFigure);
            app.BlueCloud2.ImageSource = fullfile(pathToMLAPP, 'BlueCloud.png');
            app.BlueCloud1 = uiimage(app.UIFigure);
            app.BlueCloud1.ImageSource = fullfile(pathToMLAPP, 'BlueCloud.png');
            app.CloudBlowing = uiimage(app.UIFigure);
            app.CloudBlowing.ImageSource = fullfile(pathToMLAPP, 'CloudAirBlow.png');
            app.WindSpeedLabel = uilabel(app.UIFigure);
            app.WindSpeedLabel.Text = 'WIND TUNNEL SPEED (m/s)';
            app.SpeedReadoutLabel = uilabel(app.UIFigure);
            app.SpeedReadoutLabel.Text = '';
            app.AngleofAttackGauge = uigauge(app.UIFigure, 'semicircular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end